classdef MainAppUpdated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DownloadImageButton     matlab.ui.control.Button
        EditEvaluationButton    matlab.ui.control.Button
        FinishEditButton        matlab.ui.control.Button
        Evaluation              matlab.ui.control.TextArea
        UploadImageButton_2     matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        UploadImageButton       matlab.ui.control.Button
        ChangeImageButton       matlab.ui.control.Button
        ProceedButton           matlab.ui.control.Button
        ImageProcessed          matlab.ui.control.Image
        ImageRaw                matlab.ui.control.Image
        ImageNoiseReduced       matlab.ui.control.Image
        ImageAfterSegmentation  matlab.ui.control.Image
    end

    
    properties (Access = private)
        RawImageData %Raw Image
        DenoisedData %Denoised Image
        BinaryMask   %Region of Interest Identifier
        FinalOutput  %Final Image 
        BorderMask
    end


methods (Access = private)
        % --- Parameter Estimation Stage ---
        function nIters = autoEstimateDenoiseStrength(~, img)
            noiseVar = max(0, mean2(stdfilt(img).^2));
            nIters = round(min(12, max(3, noiseVar * 50)));
        end

        function otsuLevel = autoEstimateSensitivity(~, img)
            otsuLevel = graythresh(img);
        end

        function [minArea, maxArea] = autoEstimateMorphParams(~, img)
            [numRows, numCols] = size(img);
            totalPixels = numRows * numCols;
            minArea = max(50, round(totalPixels * 0.001));
            maxArea = round(totalPixels * 0.08);
        end

        % --- Core Processing Functions ---
        function denoisedImg = runDenoisingStage(~, inputImg, filterStrength)
            denoisedImg = imdiffusefilt(inputImg, ...
                'GradientThreshold', 0.1, ...
                'NumberOfIterations', filterStrength);
        end

        function segMask = runSegmentationStage(app, denoisedImg, otsuLevel)
            % 1. Tighten threshold so background shadows aren't selected
            thresholdVal = otsuLevel * 0.80; 
            segMask = denoisedImg < thresholdVal;
            
            % 2. First apply ROI border mask
            segMask = segMask & app.BorderMask;
            
            % 3. Clear objects touching the inner ROI boundary
            % (Creates a 1-pixel border on BorderMask so inset blobs get cleared)
            segMask = segMask & app.BorderMask;
            segMask = imclearborder(segMask);
        end

function cleanMask = runMorphologicalStage(~, binaryMask, minArea, maxArea)
            % 1. Remove tiny noise speckles
            cleanMask = bwareaopen(binaryMask, minArea);
            
            % 2. Smooth boundaries and bridge internal gaps
            se = strel('disk', 2);
            cleanMask = imclose(cleanMask, se);
            cleanMask = imfill(cleanMask, 'holes');
            
            % 3. Watershed separation for touching follicles
            D = -bwdist(~cleanMask);
            D = imimposemin(D, imextendedmin(D, 1.5));
            L = watershed(D);
            cleanMask(L == 0) = false;
            
            % 4. Strict Shape Filtering
            stats = regionprops(cleanMask, 'Area', 'Perimeter', 'Solidity', ...
                                'Eccentricity', 'Extent', 'Centroid');
            [imgRows, imgCols] = size(binaryMask);
            imgCenter = [imgCols/2, imgRows*0.52];
            maxAllowedDist = min(imgCols, imgRows) * 0.42;
            validIndices = [];
            
            for k = 1:length(stats)
                area = stats(k).Area;
                perim = stats(k).Perimeter;
                solidity = stats(k).Solidity;
                eccentricity = stats(k).Eccentricity;
                extent = stats(k).Extent; % <-- Fixed: Added missing assignment
                centroid = stats(k).Centroid;
                
                circularity = (4 * pi * area) / (perim^2);
                distFromCenter = norm(centroid - imgCenter);
                
                if area >= minArea && area <= maxArea && ...
                   solidity > 0.70 && ...
                   circularity > 0.15 && ...
                   eccentricity < 0.98 && ...
                   extent < 0.85 && ...
                   distFromCenter <= maxAllowedDist
                   
                    validIndices = [validIndices; k];
                end
            end
            
            % Reconstruct final filtered mask
            cleanMask = ismember(bwlabel(cleanMask), validIndices);
        end

        % --- Rendering & Display Handler ---
        function renderVisualResults(app)
            % Mask final output
            app.FinalOutput = app.DenoisedData;
            app.FinalOutput(~app.BinaryMask) = 0;

            % Assign component sources
            app.ImageRaw.ImageSource = app.matrixToImageSource(app.RawImageData);
            app.ImageNoiseReduced.ImageSource = app.matrixToImageSource(app.DenoisedData);
            app.ImageAfterSegmentation.ImageSource = app.matrixToImageSource(double(app.BinaryMask));

            % Generate boundary overlay
            overlayImg = app.createBoundaryOverlay(app.DenoisedData, app.BinaryMask);
            app.ImageProcessed.ImageSource = app.matrixToImageSource(overlayImg);
        end

        % --- Master Pipeline Execution ---
        function executeAutoPipeline(app)
            if isempty(app.RawImageData)
                return;
            end
            
            autoDenoiseIter = app.autoEstimateDenoiseStrength(app.RawImageData);
            autoSens        = app.autoEstimateSensitivity(app.RawImageData);
            [autoMinArea, autoMaxArea] = app.autoEstimateMorphParams(app.RawImageData);

            app.DenoisedData = app.runDenoisingStage(app.RawImageData, autoDenoiseIter);
            app.BinaryMask   = app.runSegmentationStage(app.DenoisedData, autoSens);
            app.BinaryMask   = app.runMorphologicalStage(app.BinaryMask, autoMinArea, autoMaxArea);
            
            app.renderVisualResults();
        end

        function normImg = prepareRawImage(app, rawMatrix)
            if size(rawMatrix, 3) == 3
                rawMatrix = rgb2gray(rawMatrix);
            end
            normImg = im2double(rawMatrix);
            [rows, cols] = size(normImg);
            
            borderMask = false(rows, cols);
            borderMask(round(rows*0.05):round(rows*0.95), round(cols*0.05):round(cols*0.95)) = true;
            app.BorderMask = borderMask;
        end

        function imgSource = matrixToImageSource(~, imgMatrix)
            tempPath = [tempname, '.png'];
            imwrite(imgMatrix, tempPath);
            imgSource = tempPath;
        end

        function rgbOverlay = createBoundaryOverlay(~, baseImg, mask)
            rgbOverlay = repmat(baseImg, [1, 1, 3]);
            boundaries = bwperim(mask);
            
            rChannel = rgbOverlay(:,:,1); rChannel(boundaries) = 1.0; rgbOverlay(:,:,1) = rChannel;
            gChannel = rgbOverlay(:,:,2); gChannel(boundaries) = 1.0; rgbOverlay(:,:,2) = gChannel;
            bChannel = rgbOverlay(:,:,3); bChannel(boundaries) = 0.0; rgbOverlay(:,:,3) = bChannel;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChangeImageButton, UploadImageButton, 
        % ...and 1 other component
        function UploadImageButtonPushed(app, event)
        % Configure acceptable graphic extensions
            fileFilters = {'*.png;*.jpg;*.jpeg;*.bmp;*.tif', 'Image Files (*.png, *.jpg, *.jpeg, *.bmp, *.tif)'};
    
            % Open user navigation window
            [filename, pathname] = uigetfile(fileFilters, 'Select an Image');
    
            % Verify a valid file was selected
            if ~isequal(filename,0) && ~isequal(pathname,0)
                fullFilePath = fullfile(pathname, filename);
                
                % Store file path in primary UI Image component
                app.Image.ImageSource = fullFilePath;
                
                % Read raw image matrix and normalize for backend processing
                rawMatrix = imread(fullFilePath);
                app.RawImageData = app.prepareRawImage(rawMatrix);
                
                % Update visibility
                app.UploadImageButton.Visible = 'off';
                app.ChangeImageButton.Visible = 'on';
                app.ProceedButton.Visible = 'on';
                app.ImageProcessed.Visible = 'off';
                app.ImageRaw.Visible = 'off';
                app.ImageNoiseReduced.Visible = 'off';
                app.ImageAfterSegmentation.Visible = 'off';
                app.UploadImageButton_2.Visible = 'off';
                app.EditEvaluationButton.Visible = 'off';
                app.DownloadImageButton.Visible = 'off';
                app.Image.Visible = 'on'
                app.Evaluation.Visible= 'off';
            end
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            % Hide Previous Buttons and Main Preview Image
            app.ProceedButton.Visible = 'off';
            app.ChangeImageButton.Visible = 'off';
            app.Image.Visible = 'off';
            
            % Show Processing UI components
            app.ImageProcessed.Visible = 'on';
            app.ImageRaw.Visible = 'on';
            app.ImageNoiseReduced.Visible = 'on';
            app.ImageAfterSegmentation.Visible = 'on';
            app.UploadImageButton_2.Visible = 'on';
            app.EditEvaluationButton.Visible = 'on';
            app.Evaluation.Visible = 'on';
            app.DownloadImageButton.Visible = 'on';

            % Execute the automated processing pipeline
            app.executeAutoPipeline();
        end

        % Button pushed function: EditEvaluationButton
        function EditEvaluationButtonPushed(app, event)
            app.Evaluation.Editable = 'on';
            app.FinishEditButton.Visible = 'on';
            app.EditEvaluationButton.Visible = 'off';
        end

        % Button pushed function: FinishEditButton
        function FinishEditButtonButtonPushed(app, event)
            app.Evaluation.Editable = 'off';
            app.FinishEditButton.Visible = 'off';
            app.EditEvaluationButton.Visible = 'on';
        end

        % Image clicked function: ImageRaw
        function ImageRawClicked(app, event)
            tempSource1 = app.ImageProcessed.ImageSource;
            app.ImageProcessed.ImageSource = app.ImageRaw.ImageSource;
            app.ImageRaw.ImageSource = tempSource1;
        end

        % Image clicked function: ImageNoiseReduced
        function ImageNoiseReducedClicked(app, event)
            tempSource2 = app.ImageProcessed.ImageSource;
            app.ImageProcessed.ImageSource = app.ImageNoiseReduced.ImageSource;
            app.ImageNoiseReduced.ImageSource = tempSource2;
        end

        % Image clicked function: ImageAfterSegmentation
        function ImageAfterSegmentationClicked(app, event)
            tempSource3 = app.ImageProcessed.ImageSource;
            app.ImageProcessed.ImageSource = app.ImageAfterSegmentation.ImageSource;
            app.ImageAfterSegmentation.ImageSource = tempSource3;
        end

        % Button pushed function: DownloadImageButton
        function DownloadImageButtonPushed(app, event)
            currentPath = app.ImageProcessed.ImageSource;

            [filename, filepath] = uiputfile({'*.png', 'PNG Image (*.png)'; ...
                '*.jpg', 'JPEG Image (*.jpg)'}, ...
                'Save Image As');

            if ischar(filename) || isstring(filename)
                destinationPath = fullfile(filepath, filename);
                copyfile(currentPath, destinationPath);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAfterSegmentation = uiimage(app.UIFigure);
            app.ImageAfterSegmentation.ImageClickedFcn = createCallbackFcn(app, @ImageAfterSegmentationClicked, true);
            app.ImageAfterSegmentation.Visible = 'off';
            app.ImageNoiseReduced = uiimage(app.UIFigure);
            app.ImageNoiseReduced.ImageClickedFcn = createCallbackFcn(app, @ImageNoiseReducedClicked, true);
            app.ImageNoiseReduced.Visible = 'off';
            app.ImageRaw = uiimage(app.UIFigure);
            app.ImageRaw.ImageClickedFcn = createCallbackFcn(app, @ImageRawClicked, true);
            app.ImageRaw.Visible = 'off';
            app.ImageProcessed = uiimage(app.UIFigure);
            app.ImageProcessed.Visible = 'off';
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Visible = 'off';
            app.ProceedButton.Text = 'Proceed';
            app.ChangeImageButton = uibutton(app.UIFigure, 'push');
            app.ChangeImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.ChangeImageButton.Visible = 'off';
            app.ChangeImageButton.Text = 'Change Image';
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.Image = uiimage(app.UIFigure);
            app.UploadImageButton_2 = uibutton(app.UIFigure, 'push');
            app.UploadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton_2.Visible = 'off';
            app.UploadImageButton_2.Text = 'Upload Image';
            app.Evaluation = uitextarea(app.UIFigure);
            app.Evaluation.Editable = 'off';
            app.Evaluation.Visible = 'off';
            app.FinishEditButton = uibutton(app.UIFigure, 'push');
            app.FinishEditButton.ButtonPushedFcn = createCallbackFcn(app, @FinishEditButtonButtonPushed, true);
            app.FinishEditButton.Visible = 'off';
            app.FinishEditButton.Text = 'Finish Edit';
            app.EditEvaluationButton = uibutton(app.UIFigure, 'push');
            app.EditEvaluationButton.ButtonPushedFcn = createCallbackFcn(app, @EditEvaluationButtonPushed, true);
            app.EditEvaluationButton.Visible = 'off';
            app.EditEvaluationButton.Text = 'Edit Evaluation';
            app.DownloadImageButton = uibutton(app.UIFigure, 'push');
            app.DownloadImageButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadImageButtonPushed, true);
            app.DownloadImageButton.Visible = 'off';
            app.DownloadImageButton.Text = 'Download Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainAppUpdated

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end